classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        scoreEditField       matlab.ui.control.EditField
        scoreEditFieldLabel  matlab.ui.control.Label
        LabelEditField       matlab.ui.control.EditField
        LabelEditFieldLabel  matlab.ui.control.Label
        salt_and_peper       matlab.ui.control.Button
        Button               matlab.ui.control.Button
        Switch               matlab.ui.control.ToggleSwitch
        SwitchLabel          matlab.ui.control.Label
        cameraButton         matlab.ui.control.Button
        TextArea             matlab.ui.control.TextArea
        loadingLamp          matlab.ui.control.Lamp
        loadingLampLabel     matlab.ui.control.Label
        takepictureButton    matlab.ui.control.Button
        output               matlab.ui.control.UIAxes
        input                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %all variables are global acces via app.variable
        %webcam variable
        cam = webcam(1);
        %input image variable
        inputImg;
        net;
       
    end

    properties (Access = private)
        % Main app
        GUIapp;
        % Dialog box app
        DialogApp;
    end

    methods(Access = public)
        function getOutputImage(app,img)
               imshow(img,'Parent',app.output);
        end
        function getInputImage(app,img)
               imshow(img,'Parent',app.input);
        end
        function sendInfo(app,info)
               app.DisplayInfo(info)
        end
        function setScore(app,score)
            app.scoreEditField.Value = score;
        end
        function setLabel(app,label)
            app.LabelEditField.Value = label;
        end
    end

    methods (Access = private)
        function InputCamera(app)
            frame = snapshot(app.cam);
            im = image(app.input, zeros(size(frame), 'uint8'));
            axis(app.input, 'image');
            preview(app.cam, im);
        end

        %function for taking picture
        function Picture(app)
            app.inputImg = snapshot(app.cam);
            imshow(app.inputImg,'Parent',app.input);
        end
        
        function Loading(app)
            %imshow('/img/loading.gif','Parent',app.input);
            app.loadingLamp.Color = "red";
        end
        
        function FinishedLoading(app)
            app.loadingLamp.Color = "green";
        end

        function DisplayInfo(app,info)
            app.TextArea.Value = info;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)

        end

        % Button pushed function: takepictureButton
        function takepictureButtonPushed(app, event)
            app.Loading();
            app.Picture();
            app.FinishedLoading();
            app.DisplayInfo("this is a picture");
        end

        % Button pushed function: cameraButton
        function cameraButtonPushed(app, event)
            %app.InputCamera();
            app.DialogApp = app1(app);
        end

        % Button pushed function: salt_and_peper
        function salt_and_peperButtonPushed(app, event)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if(app.Switch.Value == "On")
                app.salt_and_peper.Visible = "on";
                app.DisplayInfo(app.Switch.Value)
            else
               app.salt_and_peper.Visible = "Off";
               app.DisplayInfo(app.Switch.Value)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.input = uiaxes(app.UIFigure);
            title(app.input, 'input')
            app.input.XTick = [];
            app.input.YTick = [];
            app.output = uiaxes(app.UIFigure);
            title(app.output, 'output')
            app.output.XTick = [];
            app.output.YTick = [];
            app.takepictureButton = uibutton(app.UIFigure, 'push');
            app.takepictureButton.ButtonPushedFcn = createCallbackFcn(app, @takepictureButtonPushed, true);
            app.takepictureButton.Text = 'take picture';
            app.loadingLampLabel = uilabel(app.UIFigure);
            app.loadingLampLabel.Text = 'loading';
            app.loadingLamp = uilamp(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.cameraButton = uibutton(app.UIFigure, 'push');
            app.cameraButton.ButtonPushedFcn = createCallbackFcn(app, @cameraButtonPushed, true);
            app.cameraButton.Text = 'camera';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ItemsData = {'Off', 'On'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Visible = 'off';
            app.salt_and_peper = uibutton(app.UIFigure, 'push');
            app.salt_and_peper.ButtonPushedFcn = createCallbackFcn(app, @salt_and_peperButtonPushed, true);
            app.salt_and_peper.Visible = 'off';
            app.salt_and_peper.Text = 'salt & peper';
            app.LabelEditFieldLabel = uilabel(app.UIFigure);
            app.LabelEditField = uieditfield(app.UIFigure, 'text');
            app.LabelEditField.Editable = 'off';
            app.scoreEditFieldLabel = uilabel(app.UIFigure);
            app.scoreEditFieldLabel.Text = 'score';
            app.scoreEditField = uieditfield(app.UIFigure, 'text');
            app.scoreEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end