classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp % Main app
        inputImg;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%             cam = webcam(1);
%             frame = snapshot(cam);
%             im = image(app.input, zeros(size(frame), 'uint8'));
%             axis(app.input, 'image');
%             getImage(app.CallingApp,frame)
%             preview(cam, im);

            % we load the saved workspace
              load('resNet_trained_1.mat')
%             % we make an instance to use the trained model
              net = trainedNetwork_ResNet50;
%             
%             %227 227
              inputSize = net.Layers(1).InputSize(1:2);
              camera = webcam(1);
%             
              h = figure;
%             
              while ishandle(h)
                  im = snapshot(camera);
                  image(im);
                  im = imresize(im,inputSize);
                  [label,score] = classify(net,im);
                  getImage(app.CallingApp,im); 
                  title({char(label), num2str(max(score),2)});
                  drawnow
              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end